classdef sigPreProcessAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
        FunctionSettingsPanel           matlab.ui.container.Panel
        SaveButton                      matlab.ui.control.Button
        DefaultsButton                  matlab.ui.control.Button
        FunctionDetailLabel             matlab.ui.control.Label
        SelectFolderButton              matlab.ui.control.Button
        CurrentFolderEditField          matlab.ui.control.EditField
        CurrentFolderEditFieldLabel     matlab.ui.control.Label
        StatusTextArea                  matlab.ui.control.TextArea
        StatusTextAreaLabel             matlab.ui.control.Label
        httpsgithubcomcincibrainlabsigPreProcessAnalyzerLabel  matlab.ui.control.Label
        EEGPreprocessingAnalyzerv1Label  matlab.ui.control.Label
        PostProcessingPanel             matlab.ui.container.Panel
        BatchComputeButton              matlab.ui.control.Button
        eeg_htpEegAssessPipelineHAPPE_TableOnly  matlab.ui.control.Button
        eeg_htpEegAssessPipelineHAPPE_CopyClipboard  matlab.ui.control.Button
        eeg_htpEegAssessPipelineHAPPE_ExportCsv  matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        Label                           matlab.ui.control.Label
        ahrefQuantitativeQAMetricsviaeeg_htpEegPipelineAssessmentaLabel  matlab.ui.control.Label
        eeg_htpEegAssessPipelineHAPPE   matlab.ui.control.Button
        dualEegplot                     matlab.ui.control.Button
        View2Button                     matlab.ui.control.Button
        View1Button                     matlab.ui.control.Button
        SelectFileFromLabel             matlab.ui.control.Label
        SET2EditField                   matlab.ui.control.EditField
        SET2EditFieldLabel              matlab.ui.control.Label
        SET1EditField                   matlab.ui.control.EditField
        SET1Label                       matlab.ui.control.Label
        OutputButton_2                  matlab.ui.control.Button
        OutputButton                    matlab.ui.control.Button
        InputButton_2                   matlab.ui.control.Button
        InputButton                     matlab.ui.control.Button
        PipelineConstructionPanel       matlab.ui.container.Panel
        MultipleFileModeLabel           matlab.ui.control.Label
        SaveEditsButton                 matlab.ui.control.Button
        CreateSETButton                 matlab.ui.control.Button
        GeneratedCodeTextArea           matlab.ui.control.TextArea
        GeneratedCodeTextAreaLabel      matlab.ui.control.Label
        PreprocessingPanel              matlab.ui.container.Panel
        EditFunctionActivateButtonGroup  matlab.ui.container.ButtonGroup
        PresetDropDown                  matlab.ui.control.DropDown
        PresetDropDownLabel             matlab.ui.control.Label
        UpdateOrderButton_2             matlab.ui.control.Button
        MakePipelineButton              matlab.ui.control.Button
        Switch_2                        matlab.ui.control.Switch
        Switch                          matlab.ui.control.Switch
        HighPassFilterButton            matlab.ui.control.RadioButton
        ResampleButton                  matlab.ui.control.RadioButton
        FilesPanel                      matlab.ui.container.Panel
        SelectAllOutputFilesCheckBox    matlab.ui.control.CheckBox
        SelectAllInputFilesCheckBox     matlab.ui.control.CheckBox
        OutputFolderEditField           matlab.ui.control.EditField
        OutputFolderButton              matlab.ui.control.Button
        OutputFilesListBox              matlab.ui.control.ListBox
        GeneratedSetsListBoxLabel       matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        EEGStructureofSelectedSETLabel  matlab.ui.control.Label
        InputFileListBox                matlab.ui.control.ListBox
        AvailableSETfilesinCurrentPathListBoxLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        spac % reserved for controller class
        pipeButton              matlab.ui.control.RadioButton;
        pipeSwitch              matlab.ui.control.Switch;
        pipeOrder               matlab.ui.control.NumericEditField;
        pipeInputLabel      matlab.ui.control.Label;
        pipeInputEditField      matlab.ui.control.EditField;
    end

    methods (Access = private)

        function updateDirectoryEditFields(app, foldertype)
            switch foldertype
                case 'inputfolder'
                    app.CurrentFolderEditField.Value = app.spac.current_folder;
                    % list files in file table from current directory
                    app.spac.openFolder( app.spac.current_folder, 'inputfolder' );
                    app.InputFileListBox.Items = app.spac.current_files;
                case 'outputfolder'

                    app.OutputFolderEditField.Value = app.spac.current_output_folder;
                    % list files in file table from current directory
                    app.spac.openFolder( app.spac.current_output_folder, 'outputfolder' );
                    app.OutputFilesListBox.Items = app.spac.current_output_files;
            end


        end

        function results = setupPipelineLayout(app)

            ppTbl =  app.spac.panel_config;

            ppTbl = sortrows(ppTbl, {'ORDER'});

            query =  ppTbl.PANEL == "PREPROCESS" & ppTbl.SUBPANEL == "PIPELINE";

            query_results = ppTbl(query, :);

            yinit = 0;
            yoffset = 26; % px

            % preallocation

            for i = 1 : height(query_results)

                app.pipeButton(i) =  uiradiobutton(app.EditFunctionActivateButtonGroup);
                app.pipeSwitch(i) = uiswitch(app.EditFunctionActivateButtonGroup, 'slider');
                app.pipeOrder(i) = uieditfield(app.EditFunctionActivateButtonGroup, 'numeric');

            end

            paneHeight = app.EditFunctionActivateButtonGroup.Position(4);
            parameterHeight = 26 *  height(query_results) ;
            topMargin = 20;
            finalYoffset = paneHeight - parameterHeight - topMargin;
            ystart = yinit + finalYoffset;

            for i = height(query_results):-1:1

                uilabel =  query_results.LABEL{i,1};
                uifx =  query_results.FUNCTION{i,1};

                % uiorder =  query_results.ORDER(i);

                ppTbl.ORDER(i) = i;

                % Reprogram Preallocated switches
                app.pipeButton(i).Text = uilabel;
                app.pipeButton(i).UserData = uifx;

                app.pipeButton(i).Position = [30 ystart 150 22];
                app.pipeButton(i).Value = false;

                app.pipeSwitch(i).Items   = {'Off','On'};
                app.pipeSwitch(i).Position = [190 ystart+4 35 22];

                app.pipeOrder(i).Position =  [2 ystart 25 22];
                app.pipeOrder(i).Value = i;

                ystart = ystart + yoffset;
            end

        end


        function results = setupDetailLayout(app)
            detailTbl = app.spac.current_detailTable;
            %clean up

            delete(findobj(app.FunctionSettingsPanel,'type','uilabel'))
            delete(findobj(app.FunctionSettingsPanel,'type','uieditfield'))

            if ~isempty(detailTbl)
                sourcename = char(unique(detailTbl.SOURCE));
                switch sourcename
                    case 'vhtp'
                        href = 'https://github.com/cincibrainlab/vhtp/blob/main/';
                    case 'eeglab'
                        href = 'https://github.com/sccn/eeglab/blob/develop/functions/popfunc/';
                    otherwise
                        href = 'https://google.com/search?q=';
                end

                fxname = unique(detailTbl.FUNCTION);

                app.StatusTextArea.FontColor = 'k';
                app.StatusTextArea.Value = sprintf("Input Details for %s.", fxname{1});

                ghlink = sprintf("<a href=%s%s.m>%s</a>", ...
                    href, fxname{1}, fxname{1});

                app.FunctionDetailLabel = uilabel(app.FunctionSettingsPanel);
                app.FunctionDetailLabel.Text = ghlink;
                app.FunctionDetailLabel.Interpreter = 'html';
                app.FunctionDetailLabel.Visible = 'on';
                app.FunctionDetailLabel.UserData = fxname{1};
                app.FunctionDetailLabel.Position = [9 307 232 22];
                yinit = 0;
                topMargin = 40;
                yoffset = 25; % px
                paneHeight = app.FunctionSettingsPanel.Position(4);
                parameterHeight = 25 *  height(detailTbl) ;
                finalYoffset = paneHeight - parameterHeight - topMargin;
                ystart = yinit + finalYoffset;

                % Preallocate Inputs
                for i = 1 : height(detailTbl)
                    app.pipeInputLabel(i)     =  uilabel(app.FunctionSettingsPanel);
                    app.pipeInputEditField(i) =  uieditfield(app.FunctionSettingsPanel);
                end

                % check if previous values exist
                savedMaps = categorical(fieldnames(app.spac.pipemap));

                if any(savedMaps == fxname{1})
                    previousValues = true;
                    current_map = app.spac.pipemap.(fxname{1});
                    current_value = flip( current_map.values );
                else
                    previousValues = false;
                end

                for i = height(detailTbl):-1:1
                    current_row = detailTbl(i,:);
                    app.pipeInputLabel(i).Text = detailTbl{i,{'INPUT'}};
                    app.pipeInputLabel(i).Tooltip = detailTbl{i,"DESCRIPTION"};

                    if previousValues
                        app.pipeInputEditField(i).Value = current_value{i};
                    end

                    app.pipeInputLabel(i).Position = [12 ystart 100 22];
                    app.pipeInputEditField(i).Position = [101 ystart 152 22];
                    ystart = ystart + yoffset;
                end
            else
                app.StatusTextArea.FontColor = 'r';
                app.StatusTextArea.Value = sprintf("No function defined in spaControllerPanelDetail.csv");
            end

        end

        function results = multiFileHandler(app, key)

            switch key
                case 'MultiInputSelect'
                    app.MultipleFileModeLabel.Visible = 1;
                    app.spac.multiFileMode = true;
                    app.InputFileListBox.Multiselect = true;
                    app.InputFileListBox.Value = ...
                        app.InputFileListBox.Items;
                    app.spac.multiInputFiles = ...
                        app.InputFileListBox.Items;

                case 'MultiOutputSelect'
                    app.MultipleFileModeLabel.Visible = 1;
                    app.spac.multiFileMode = true;
                    app.OutputFilesListBox.Multiselect = true;
                    app.OutputFilesListBox.Value = ...
                        app.OutputFilesListBox.Items;
                    app.spac.multiOutputFiles = ...
                        app.OutputFilesListBox.Items;

                case 'MultiInputDeselect'
                    app.MultipleFileModeLabel.Visible = 0;
                    app.spac.multiFileMode = false;
                    app.InputFileListBox.Multiselect = false;
                    app.InputFileListBox.Value = {};
                    app.spac.multiInputFiles = [];

                case 'MultiOutputDeselect'
                    app.MultipleFileModeLabel.Visible = 0;
                    app.spac.multiFileMode = false;
                    app.OutputFilesListBox.Multiselect = false;
                    app.OutputFilesListBox.Value = {};
                    app.spac.multiOutputFiles = [];

                case 'createMultiFilePipeline'
                    % create pipeline
                    pipelineOn = categorical({app.pipeSwitch.Value}) == "On";

                    if any(pipelineOn)
                        pipelist = categorical({app.pipeButton.UserData});
                        active_fx = pipelist(pipelineOn);

                        for i = 1 : numel(active_fx)
                            cmdstr{i} = app.spac.generatePipeCmd(active_fx(i));
                            % app.StatusTextArea.Value = app.spac.getPipeEvalCmd(active_fx(i));
                            % app.spac.pipestack
                        end
                    end

                    multicmd{1} = sprintf('for i = 1 : numel(%s)');
                    str = [];
                    for i = 1 : numel(app.spac.multiInputFiles)
                        setfile = fullfile(app.spac.current_folder, ...
                            app.spac.multiInputFiles{i});
                        if i == 1
                            str = sprintf('filelist = {''%s'', ...\n', setfile );
                        end
                        str = sprintf('%s''%s'',...\n', str, setfile );
                        if i == numel(app.spac.multiInputFiles)
                            str = sprintf('%s''%s''}\n', str,setfile );
                        end
                    end


            end

        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.spac = spaController();
            app.StatusTextArea.Value = sprintf("%s", app.StatusTextArea.Value{1}, "Created Controller Class");

            app.spac.setScriptFolder( ...
                fileparts(mfilename('fullpath'))); % absolute path to the folder containing the mlapp file

            if app.spac.saveSettingsExists
                app.spac.loadSettings
                app.updateDirectoryEditFields('inputfolder');
            end

            % app.MakePipelineButton.Enable = 'off';
            app.setupPipelineLayout;
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            %create a dummy figure so that uigetdir doesn't minimize our GUI
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            app.spac.selectFolder('inputfolder');
            app.updateDirectoryEditFields('inputfolder');
            delete(f); %delete the dummy figure

            % save settings
            app.spac.saveSettings;

        end

        % Value changed function: InputFileListBox
        function InputFileListBoxValueChanged(app, event)
            value = app.InputFileListBox.Value;

            % allow ability to run pipeline if file is selected
            % app.MakePipelineButton.Enable = 'on';

            % populate details
            app.spac.showSetStructure( value );
            app.TextArea.Value = app.spac.current_details;

            % update user selected file
            app.spac.selected_file = value;

        end

        % Button pushed function: UpdateOrderButton_2
        function UpdateOrderButton_2Pushed(app, event)

            % get current order
            app.spac.storeNewPipeOrder([app.pipeOrder.Value]);

            % check for duplicates

            % modify datatable
            app.spac.updateConfigCsv('updateOrder');

            % rerun button generation
            app.setupPipelineLayout;

        end

        % Selection changed function: 
        % EditFunctionActivateButtonGroup
        function EditFunctionActivateButtonGroupSelectionChanged(app, event)
            % get user input
            selectedButton = app.EditFunctionActivateButtonGroup.SelectedObject;
            functionColumn = categorical( app.spac.panel_config.LABEL );

            % identify correct row
            fxnIdx = find(functionColumn == selectedButton.Text);
            queryrow = app.spac.panel_config(fxnIdx,:);

            % store detail information regarding selected function
            app.spac.getDetailTableByFunName( queryrow.FUNCTION{1} );

            app.setupDetailLayout()

        end

        % Button pushed function: MakePipelineButton
        function MakePipelineButtonPushed(app, event)

            % check which pipeline items are on
            pipelineOn = categorical({app.pipeSwitch.Value}) == "On";


            if app.spac.multiFileMode
                app.multiFileHandler('createMultiFilePipeline');
            else

            end

            % load EEG
            app.spac.loadEEG;

            if any(pipelineOn)
                pipelist = categorical({app.pipeButton.UserData});
                active_fx = pipelist(pipelineOn);

                for i = 1 : numel(active_fx)
                    app.spac.applyPipeFx(active_fx(i));
                    app.StatusTextArea.Value = app.spac.getPipeEvalCmd(active_fx(i));
                    % app.spac.pipestack

                end
            else
                % load and plot EEG timeseries
                app.spac.loadEEG;
            end

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % get function name
            fxname = app.FunctionDetailLabel.UserData;

            % get key value pairs
            values = {app.pipeInputEditField.Value};
            keys = cellfun( @(x) char(x), {app.pipeInputLabel.Text}, 'uni', 0);

            mapObj = containers.Map(keys,values);

            app.spac.storeMap( fxname, mapObj );

        end

        % Button pushed function: OutputFolderButton
        function OutputFolderButtonPushed(app, event)
            %create a dummy figure so that uigetdir doesn't minimize our GUI
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            app.spac.selectFolder('outputfolder');
            app.updateDirectoryEditFields('outputfolder');
            delete(f); %delete the dummy figure

            % save settings
            app.spac.saveSettings;
        end

        % Button pushed function: InputButton
        function InputButtonPushed(app, event)
            if ~isempty( app.InputFileListBox.Value )
                app.SET1EditField.Value = app.InputFileListBox.Value;
                app.spac.EEG1 = app.spac.loadSet(app.SET1EditField.Value, app.CurrentFolderEditField.Value);
            else
                app.SET1EditField.Value = "No source file selected.";
            end
        end

        % Button pushed function: InputButton_2
        function InputButton_2Pushed(app, event)
            if ~isempty( app.InputFileListBox.Value )
                app.SET2EditField.Value = app.InputFileListBox.Value;
                app.spac.EEG2 = app.spac.loadSet(app.SET2EditField.Value, app.CurrentFolderEditField.Value);
            else
                app.SET2EditField.Value = "No source file selected.";
            end
        end

        % Button pushed function: OutputButton
        function OutputButtonPushed(app, event)
            if ~isempty( app.OutputFilesListBox.Value )
                app.SET1EditField.Value = app.OutputFilesListBox.Value;
                app.spac.EEG1 = app.spac.loadSet(app.SET1EditField.Value, app.OutputFolderEditField.Value);

            else
                app.SET1EditField.Value = "No source file selected.";
            end
        end

        % Button pushed function: OutputButton_2
        function OutputButton_2Pushed(app, event)
            if ~isempty( app.OutputFilesListBox.Value )
                app.SET2EditField.Value = app.OutputFilesListBox.Value;
                app.spac.EEG2 = app.spac.loadSet(app.SET2EditField.Value, app.OutputFolderEditField.Value);
            else
                app.SET2EditField.Value = "No source file selected.";
            end
        end

        % Value changed function: SET1EditField
        function SET1EditFieldValueChanged(app, event)
            value = app.SET1EditField.Value;
            app.spac.loadSet

        end

        % Callback function: UIFigure, View1Button, View2Button, 
        % dualEegplot, eeg_htpEegAssessPipelineHAPPE, 
        % eeg_htpEegAssessPipelineHAPPE_TableOnly
        function PipelineAssessmentHandler(app, event)
            key = categorical({event.Source.Text});

            switch key
                case 'Plots'
                    app.spac.EEG1 = app.spac.loadSet(app.SET1EditField.Value, app.CurrentFolderEditField.Value);
                    app.spac.EEG2 = app.spac.loadSet(app.SET2EditField.Value, app.CurrentFolderEditField.Value);
                    summary_table = eeg_htpEegAssessPipelineHAPPE(...
                        app.spac.EEG1, ...
                        app.spac.EEG2, ...
                        'outputdir', app.spac.current_output_folder, ...
                        'outputfile', 'sigPreProcessAnalyzerResults.csv');
                case 'Table'
                    app.spac.EEG1 = app.spac.loadSet(app.SET1EditField.Value, app.CurrentFolderEditField.Value);
                    app.spac.EEG2 = app.spac.loadSet(app.SET2EditField.Value, app.CurrentFolderEditField.Value);
                    summary_table = eeg_htpEegAssessPipelineHAPPE(...
                        app.spac.EEG1, ...
                        app.spac.EEG2, ...
                        'tableonly', true, ...
                        'outputdir', app.spac.current_output_folder, ...
                        'outputfile', 'sigPreProcessAnalyzerResults.csv');
                case 'View #1'
                    app.spac.EEG1 = app.spac.loadSet(app.SET1EditField.Value, app.CurrentFolderEditField.Value);
                    EEGBrowser(app.spac.EEG1);
                case 'View #2'
                    app.spac.EEG2 = app.spac.loadSet(app.SET2EditField.Value, app.CurrentFolderEditField.Value);
                    EEGBrowser(app.spac.EEG2);
                case 'View SET1 & SET2'
                    app.spac.EEG1 = app.spac.loadSet(app.SET1EditField.Value, app.CurrentFolderEditField.Value);
                    app.spac.EEG2 = app.spac.loadSet(app.SET2EditField.Value, app.CurrentFolderEditField.Value);
                    plottitle = sprintf('%s (red) overlayed on %s(3 epochs displayed, 10 channels)', ...
                        app.spac.EEG2.setname, ...
                        app.spac.EEG1.setname );
                    % app.spac.EEG1.data = zeros(size(app.spac.EEG1.data))
                    eegplot(app.spac.EEG1.data, ...
                        'data2',app.spac.EEG2.data, ...
                        'srate', app.spac.EEG1.srate, ...
                        'winlength', 3, 'dispchans', 10, 'spacing', 50, ...
                        'title', 'sigPreProcessAnalyzer: Compare Time Series', 'plottitle', plottitle);
            end

            switch key
                case {'Plot', 'Table'}
                    app.UITable.Data = summary_table(:,5:end);
                    app.UITable.ColumnName = summary_table.Properties.VariableNames(5:end);
                    app.spac.last_summary_table = summary_table;
            end


        end

        % Value changed function: SelectAllInputFilesCheckBox
        function SelectAllInputFilesCheckBoxValueChanged(app, event)
            value = app.SelectAllInputFilesCheckBox.Value;
            if value
                key = 'MultiInputSelect';
                app.multiFileHandler(key)
            else
                key = 'MultiInputDeselect';
                app.multiFileHandler(key)
            end

        end

        % Value changed function: SelectAllOutputFilesCheckBox
        function SelectAllOutputFilesCheckBoxValueChanged(app, event)
            value = app.SelectAllOutputFilesCheckBox.Value;
            if value
                key = 'MultiOutputSelect';
                app.multiFileHandler(key)
            else
                key = 'MultiOutputDeselect';
                app.multiFileHandler(key)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @PipelineAssessmentHandler, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.FilesPanel = uipanel(app.UIFigure);
            app.FilesPanel.Title = 'Files';
            app.AvailableSETfilesinCurrentPathListBoxLabel = uilabel(app.FilesPanel);
            app.AvailableSETfilesinCurrentPathListBoxLabel.Text = 'Available SET files in Current Path';
            app.InputFileListBox = uilistbox(app.FilesPanel);
            app.InputFileListBox.Items = {};
            app.InputFileListBox.ValueChangedFcn = createCallbackFcn(app, @InputFileListBoxValueChanged, true);
            app.InputFileListBox.Value = {};
            app.EEGStructureofSelectedSETLabel = uilabel(app.FilesPanel);
            app.EEGStructureofSelectedSETLabel.Text = 'EEG Structure of Selected SET';
            app.TextArea = uitextarea(app.FilesPanel);
            app.GeneratedSetsListBoxLabel = uilabel(app.FilesPanel);
            app.GeneratedSetsListBoxLabel.Text = 'Generated Sets';
            app.OutputFilesListBox = uilistbox(app.FilesPanel);
            app.OutputFilesListBox.Items = {};
            app.OutputFilesListBox.Value = {};
            app.OutputFolderButton = uibutton(app.FilesPanel, 'push');
            app.OutputFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OutputFolderButtonPushed, true);
            app.OutputFolderButton.Text = 'Output Folder';
            app.OutputFolderEditField = uieditfield(app.FilesPanel, 'text');
            app.SelectAllInputFilesCheckBox = uicheckbox(app.FilesPanel);
            app.SelectAllInputFilesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelectAllInputFilesCheckBoxValueChanged, true);
            app.SelectAllInputFilesCheckBox.Text = ' Select All Input Files';
            app.SelectAllOutputFilesCheckBox = uicheckbox(app.FilesPanel);
            app.SelectAllOutputFilesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelectAllOutputFilesCheckBoxValueChanged, true);
            app.SelectAllOutputFilesCheckBox.Text = ' Select All Output Files';
            app.PreprocessingPanel = uipanel(app.UIFigure);
            app.PreprocessingPanel.Title = 'Preprocessing';
            app.EditFunctionActivateButtonGroup = uibuttongroup(app.PreprocessingPanel);
            app.EditFunctionActivateButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EditFunctionActivateButtonGroupSelectionChanged, true);
            app.EditFunctionActivateButtonGroup.Title = '  Edit Function                                Activate';
            app.ResampleButton = uiradiobutton(app.EditFunctionActivateButtonGroup);
            app.ResampleButton.Visible = 'off';
            app.ResampleButton.Text = 'Resample';
            app.ResampleButton.Value = true;
            app.HighPassFilterButton = uiradiobutton(app.EditFunctionActivateButtonGroup);
            app.HighPassFilterButton.Visible = 'off';
            app.HighPassFilterButton.Text = 'High Pass Filter';
            app.Switch = uiswitch(app.EditFunctionActivateButtonGroup, 'slider');
            app.Switch.Visible = 'off';
            app.Switch_2 = uiswitch(app.EditFunctionActivateButtonGroup, 'slider');
            app.Switch_2.Visible = 'off';
            app.MakePipelineButton = uibutton(app.EditFunctionActivateButtonGroup, 'push');
            app.MakePipelineButton.ButtonPushedFcn = createCallbackFcn(app, @MakePipelineButtonPushed, true);
            app.MakePipelineButton.Text = 'Make Pipeline';
            app.UpdateOrderButton_2 = uibutton(app.EditFunctionActivateButtonGroup, 'push');
            app.UpdateOrderButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdateOrderButton_2Pushed, true);
            app.UpdateOrderButton_2.Text = 'Update Order';
            app.PresetDropDownLabel = uilabel(app.EditFunctionActivateButtonGroup);
            app.PresetDropDownLabel.Text = 'Preset:';
            app.PresetDropDown = uidropdown(app.EditFunctionActivateButtonGroup);
            app.PresetDropDown.Items = {};
            app.PresetDropDown.Value = {};
            app.PipelineConstructionPanel = uipanel(app.UIFigure);
            app.PipelineConstructionPanel.Title = 'Pipeline Construction';
            app.GeneratedCodeTextAreaLabel = uilabel(app.PipelineConstructionPanel);
            app.GeneratedCodeTextAreaLabel.Text = 'Generated Code';
            app.GeneratedCodeTextArea = uitextarea(app.PipelineConstructionPanel);
            app.CreateSETButton = uibutton(app.PipelineConstructionPanel, 'push');
            app.CreateSETButton.Text = 'Create SET';
            app.SaveEditsButton = uibutton(app.PipelineConstructionPanel, 'push');
            app.SaveEditsButton.Text = 'Save Edits';
            app.MultipleFileModeLabel = uilabel(app.PipelineConstructionPanel);
            app.MultipleFileModeLabel.Visible = 'off';
            app.MultipleFileModeLabel.Text = 'Multiple File Mode';
            app.PostProcessingPanel = uipanel(app.UIFigure);
            app.PostProcessingPanel.Title = 'PostProcessing';
            app.InputButton = uibutton(app.PostProcessingPanel, 'push');
            app.InputButton.ButtonPushedFcn = createCallbackFcn(app, @InputButtonPushed, true);
            app.InputButton.Text = 'Input';
            app.InputButton_2 = uibutton(app.PostProcessingPanel, 'push');
            app.InputButton_2.ButtonPushedFcn = createCallbackFcn(app, @InputButton_2Pushed, true);
            app.InputButton_2.Text = 'Input';
            app.OutputButton = uibutton(app.PostProcessingPanel, 'push');
            app.OutputButton.ButtonPushedFcn = createCallbackFcn(app, @OutputButtonPushed, true);
            app.OutputButton.Text = 'Output';
            app.OutputButton_2 = uibutton(app.PostProcessingPanel, 'push');
            app.OutputButton_2.ButtonPushedFcn = createCallbackFcn(app, @OutputButton_2Pushed, true);
            app.OutputButton_2.Text = 'Output';
            app.SET1Label = uilabel(app.PostProcessingPanel);
            app.SET1Label.Text = 'SET #1:';
            app.SET1EditField = uieditfield(app.PostProcessingPanel, 'text');
            app.SET1EditField.ValueChangedFcn = createCallbackFcn(app, @SET1EditFieldValueChanged, true);
            app.SET2EditFieldLabel = uilabel(app.PostProcessingPanel);
            app.SET2EditFieldLabel.Text = 'SET #2:';
            app.SET2EditField = uieditfield(app.PostProcessingPanel, 'text');
            app.SelectFileFromLabel = uilabel(app.PostProcessingPanel);
            app.SelectFileFromLabel.Text = 'Select File From:';
            app.View1Button = uibutton(app.PostProcessingPanel, 'push');
            app.View1Button.ButtonPushedFcn = createCallbackFcn(app, @PipelineAssessmentHandler, true);
            app.View1Button.Text = 'View #1';
            app.View2Button = uibutton(app.PostProcessingPanel, 'push');
            app.View2Button.ButtonPushedFcn = createCallbackFcn(app, @PipelineAssessmentHandler, true);
            app.View2Button.Text = 'View #2';
            app.dualEegplot = uibutton(app.PostProcessingPanel, 'push');
            app.dualEegplot.ButtonPushedFcn = createCallbackFcn(app, @PipelineAssessmentHandler, true);
            app.dualEegplot.Text = 'View SET1 & SET2';
            app.eeg_htpEegAssessPipelineHAPPE = uibutton(app.PostProcessingPanel, 'push');
            app.eeg_htpEegAssessPipelineHAPPE.ButtonPushedFcn = createCallbackFcn(app, @PipelineAssessmentHandler, true);
            app.eeg_htpEegAssessPipelineHAPPE.Text = 'Plots';
            app.ahrefQuantitativeQAMetricsviaeeg_htpEegPipelineAssessmentaLabel = uilabel(app.PostProcessingPanel);
            app.ahrefQuantitativeQAMetricsviaeeg_htpEegPipelineAssessmentaLabel.Interpreter = 'html';
            app.ahrefQuantitativeQAMetricsviaeeg_htpEegPipelineAssessmentaLabel.Text = '<a href=https://github.com/cincibrainlab/vhtp/blob/main/eeg_htpEegAssessPipelineHAPPE.m>Quantitative QA via eeg_htpEegPipelineAssessment</a>';
            app.Label = uilabel(app.PostProcessingPanel);
            app.Label.Interpreter = 'html';
            app.Label.Text = '<a href=https://github.com/sccn/eeglab/blob/develop/functions/sigprocfunc/eegplot.m>Superimpose EEG2 (red) on EEG1 via eegplot</a>';
            app.UITable = uitable(app.PostProcessingPanel);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.eeg_htpEegAssessPipelineHAPPE_ExportCsv = uibutton(app.PostProcessingPanel, 'push');
            app.eeg_htpEegAssessPipelineHAPPE_ExportCsv.Text = 'Export';
            app.eeg_htpEegAssessPipelineHAPPE_CopyClipboard = uibutton(app.PostProcessingPanel, 'push');
            app.eeg_htpEegAssessPipelineHAPPE_CopyClipboard.Text = 'Copy';
            app.eeg_htpEegAssessPipelineHAPPE_TableOnly = uibutton(app.PostProcessingPanel, 'push');
            app.eeg_htpEegAssessPipelineHAPPE_TableOnly.ButtonPushedFcn = createCallbackFcn(app, @PipelineAssessmentHandler, true);
            app.eeg_htpEegAssessPipelineHAPPE_TableOnly.Text = 'Table';
            app.BatchComputeButton = uibutton(app.PostProcessingPanel, 'push');
            app.BatchComputeButton.Text = 'Batch Compute';
            app.EEGPreprocessingAnalyzerv1Label = uilabel(app.UIFigure);
            app.EEGPreprocessingAnalyzerv1Label.Text = 'EEG Preprocessing Analyzer v.1';
            app.httpsgithubcomcincibrainlabsigPreProcessAnalyzerLabel = uilabel(app.UIFigure);
            app.httpsgithubcomcincibrainlabsigPreProcessAnalyzerLabel.Interpreter = 'html';
            app.httpsgithubcomcincibrainlabsigPreProcessAnalyzerLabel.Text = '<a href=https://github.com/cincibrainlab/sigPreProcessAnalyzer>https://github.com/cincibrainlab/sigPreProcessAnalyzer</a>';
            app.StatusTextAreaLabel = uilabel(app.UIFigure);
            app.StatusTextAreaLabel.Text = 'Status:';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.CurrentFolderEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentFolderEditFieldLabel.Text = 'Current Folder:';
            app.CurrentFolderEditField = uieditfield(app.UIFigure, 'text');
            app.SelectFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.FunctionSettingsPanel = uipanel(app.UIFigure);
            app.FunctionSettingsPanel.Title = 'Function Settings';
            app.FunctionDetailLabel = uilabel(app.FunctionSettingsPanel);
            app.FunctionDetailLabel.Visible = 'off';
            app.FunctionDetailLabel.Text = 'FunctionDetailLabel';
            app.DefaultsButton = uibutton(app.FunctionSettingsPanel, 'push');
            app.DefaultsButton.Text = 'Defaults';
            app.SaveButton = uibutton(app.FunctionSettingsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sigPreProcessAnalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end